classdef StyleTransfer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StyleTransferUIFigure     matlab.ui.Figure
        IterationsEditField       matlab.ui.control.EditField
        IterationsEditFieldLabel  matlab.ui.control.Label
        ExportButton              matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        StartButton               matlab.ui.control.Button
        Input_2_Button            matlab.ui.control.Button
        Input_1_Button            matlab.ui.control.Button
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path1 
        path2
        contentImage
        styleImage
        rgbImage2
        hsvImage
        transferImage
        numIterations
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Input_1_Button
        function Input_1_ButtonPushed(app, event)
            app.path1 = uigetfile('*.jpg','*.png');
            app.contentImage = imread(app.path1);

            app.hsvImage = rgb2hsv(app.contentImage);
            % Get the separate color channels

            imshow(app.contentImage,'Parent',app.UIAxes);
            app.UIAxes.Title.String = 'Content Image';
            app.StyleTransferUIFigure.Visible = 0;
            app.StyleTransferUIFigure.Visible = 1;
        end

        % Button pushed function: Input_2_Button
        function Input_2_ButtonPushed(app, event)
            app.path2 = uigetfile('*.jpg','*.png');
            app.styleImage = imread(app.path2);
            imshow(app.styleImage,'Parent',app.UIAxes_2);
            app.UIAxes_2.Title.String = 'Style Image';
            app.StyleTransferUIFigure.Visible = 0;
            app.StyleTransferUIFigure.Visible = 1;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Load Feature Extraction Network 
            net = vgg16;
            
            lastFeatureLayerIdx = 38;
            layers = net.Layers;
            layers = layers(1:lastFeatureLayerIdx);
            
            for l = 1:lastFeatureLayerIdx
                layer = layers(l);
                if isa(layer,'nnet.cnn.layer.MaxPooling2DLayer')
                    layers(l) = averagePooling2dLayer(layer.PoolSize,'Stride',layer.Stride,'Name',layer.Name);
                end
            end
            
            lgraph = layerGraph(layers);
            
            %plot(lgraph)
            %title('Feature Extraction Network')
            
            dlnet = dlnetwork(lgraph);
            
            % Preprocess Data
            
            imageSize = [384,512];
            styleImg = imresize(app.styleImage,imageSize);
            contentImg = imresize(app.contentImage,imageSize);
            edgeImg = imresize(edge(rgb2gray(app.contentImage),'canny'),imageSize);

            app.hsvImage = rgb2hsv(contentImg);
            
            imgInputLayer = lgraph.Layers(1);
            meanVggNet = imgInputLayer.Mean(1,1,:);
            
            styleImg = rescale(single(styleImg),0,255) - meanVggNet;
            contentImg = rescale(single(contentImg),0,255) - meanVggNet;
            edgeImg = rescale(single(edgeImg),0,255) - meanVggNet;


            % Initialize Transfer Image
            noiseRatio = 0.7;
            randImage = randi([-20,20],[imageSize 3]);
            app.transferImage = noiseRatio.*randImage + (1-noiseRatio).*contentImg;
            
            % Define Loss Functions and Style Transfer Parameters 
            
            styleTransferOptions.contentFeatureLayerNames = {'conv2_2'};
            
            styleTransferOptions.contentFeatureLayerWeights = 1;

            styleTransferOptions.edgeFeatureLayerNames = {'conv1_2'};

            styleTransferOptions.edgeFeatureLayerWeights = 1;
            
            % Style Loss
            styleTransferOptions.styleFeatureLayerNames = {'conv1_1','conv2_1','conv3_1','conv4_1','conv5_1'};
            styleTransferOptions.styleFeatureLayerWeights = [0.5,1.0,1.5,3.0,4.0];
            
            % Total Loss
            styleTransferOptions.alpha = 0.025;
            styleTransferOptions.beta = 5;
            styleTransferOptions.gamma = 0.5;
            
            % Specify Training Options
            app.numIterations = str2double(app.IterationsEditField.Value);
            
            learningRate = 2;
            trailingAvg = [];
            trailingAvgSq = [];
            
            % Train Network
            dlStyle = dlarray(styleImg,'SSC');
            dlContent = dlarray(contentImg,'SSC');
            dlEdge = dlarray(edgeImg,'SSC');
            dlTransfer = dlarray(app.transferImage,'SSC');
            
            if canUseGPU
                dlContent = gpuArray(dlContent);
                dlStyle = gpuArray(dlStyle);
                dlEdge = gpuArray(dlEdge);
                dlTransfer = gpuArray(dlTransfer);
            end
            
            numContentFeatureLayers = numel(styleTransferOptions.contentFeatureLayerNames);
            contentFeatures = cell(1,numContentFeatureLayers);
            [contentFeatures{:}] = forward(dlnet,dlContent,'Outputs',styleTransferOptions.contentFeatureLayerNames);

            numEdgeFeatureLayers= numel(styleTransferOptions.edgeFeatureLayerNames);
            edgeFeatures = cell(1,numEdgeFeatureLayers);
            [edgeFeatures{:}] = forward(dlnet,dlEdge,'Outputs',styleTransferOptions.edgeFeatureLayerNames);
            
            numStyleFeatureLayers = numel(styleTransferOptions.styleFeatureLayerNames);
            styleFeatures = cell(1,numStyleFeatureLayers);
            [styleFeatures{:}] = forward(dlnet,dlStyle,'Outputs',styleTransferOptions.styleFeatureLayerNames);
            
            %
            
            minimumLoss = inf;
            
            for iteration = 1:app.numIterations
                % Evaluate the transfer image gradients and state using dlfeval and the
                % imageGradients function listed at the end of the example.
                [grad,losses] = dlfeval(@imageGradients,dlnet,dlTransfer,contentFeatures,edgeFeatures,styleFeatures,styleTransferOptions);
                [dlTransfer,trailingAvg,trailingAvgSq] = adamupdate(dlTransfer,grad,trailingAvg,trailingAvgSq,iteration,learningRate);
                
                if losses.totalLoss < minimumLoss
                    minimumLoss = losses.totalLoss;
                    dlOutput = dlTransfer;
                end
                
                 disp(iteration);
                
                % Display the transfer image on the first iteration and after every 50
                % iterations. The postprocessing steps are described in the "Postprocess
                % Transfer Image for Display" section of this example.
                if mod(iteration,50) == 0 || (iteration == 1)
                    
                    
                    app.transferImage = gather(extractdata(dlTransfer));
                    app.transferImage = app.transferImage + meanVggNet;
                    app.transferImage = uint8(app.transferImage);
                    app.transferImage = imresize(app.transferImage,size(app.contentImage,[1 2]));
                    
                    image(app.UIAxes_3, app.transferImage)
                    %app.UIAxes_3.Title.String = 
                    app.Label.Text = ['Transfer Image After Iteration ',num2str(iteration)];
                    %axis off image
                    drawnow
                    %imshow(app.transferImage,'Parent',app.UIAxes_3);
                    %app.UIAxes_3.Title.String = ['Transfer Image After Iteration ',num2str(iteration)];
                end
            end
            
            % Postprocess Transfer Image for Display


            % Supporting Functions
            function [gradients,losses] = imageGradients(dlnet,dlTransfer,contentFeatures,edgeFeatures,styleFeatures,params)
                
                % Initialize transfer image feature containers.
                numContentFeatureLayers = numel(params.contentFeatureLayerNames);
                numEdgeFeatureLayers = numel(params.edgeFeatureLayerNames);
                numStyleFeatureLayers = numel(params.styleFeatureLayerNames);
                
                transferContentFeatures = cell(1,numContentFeatureLayers);
                transferEdgeFeatures = cell(1,numEdgeFeatureLayers);
                transferStyleFeatures = cell(1,numStyleFeatureLayers);
                
                % Extract content features of transfer image.
                [transferContentFeatures{:}] = forward(dlnet,dlTransfer,'Outputs',params.contentFeatureLayerNames);

                [transferEdgeFeatures{:}] = forward(dlnet,dlTransfer,'Outputs',params.edgeFeatureLayerNames);
                
                % Extract style features of transfer image.
                [transferStyleFeatures{:}] = forward(dlnet,dlTransfer,'Outputs',params.styleFeatureLayerNames);
                
                % Compute content loss.
                cLoss = contentLoss(transferContentFeatures,contentFeatures,params.contentFeatureLayerWeights);
                
                % Compute style loss.
                sLoss = styleLoss(transferStyleFeatures,styleFeatures,params.styleFeatureLayerWeights);

                eLoss = edgeLoss(transferEdgeFeatures,edgeFeatures,params.edgeFeatureLayerWeights)
                
                % Compute final loss as weighted combination of content and style loss.
                loss = (params.alpha * cLoss) + (params.beta * sLoss) + (params.gamme *eLoss);
                
                % Calculate gradient with respect to transfer image.
                gradients = dlgradient(loss,dlTransfer);
                
                % Extract various losses.
                losses.totalLoss = gather(extractdata(loss));
                losses.contentLoss = gather(extractdata(cLoss));
                losses.edgeLoss = gather(extractdata(eLoss));
                losses.styleLoss = gather(extractdata(sLoss));
                
            end
            
            % Compute Content Loss
            function loss = contentLoss(transferContentFeatures,contentFeatures,contentWeights)
                
                loss = 0;
                for i=1:numel(contentFeatures)
                    temp = 0.5 .* mean((transferContentFeatures{1,i} - contentFeatures{1,i}).^2,'all');
                    loss = loss + (contentWeights(i)*temp);
                end
            end

            function loss = edgeLoss(transferEdgeFeatures,edgeFeatures,edgeWeights)
                
                loss = 0;
                for i=1:numel(edgeFeatures)
                    temp = 0.5 .* mean((transferEdgeFeatures{1,i} - edgeFeatures{1,i}).^2,'all');
                    loss = loss + (edgeWeights(i)*temp);
                end
            end
            
            % Compute Style Loss
            function loss = styleLoss(transferStyleFeatures,styleFeatures,styleWeights)
                
                loss = 0;
                for i=1:numel(styleFeatures)
                    
                    tsf = transferStyleFeatures{1,i};
                    sf = styleFeatures{1,i};
                    [h,w,c] = size(sf);
                    
                    gramStyle = computeGramMatrix(sf);
                    gramTransfer = computeGramMatrix(tsf);
                    sLoss = mean((gramTransfer - gramStyle).^2,'all') / ((h*w*c)^2);
                    
                    loss = loss + (styleWeights(i)*sLoss);
                end
            end
            
            %Compute Gram Matrix
            function gramMatrix = computeGramMatrix(featureMap)
                [H,W,C] = size(featureMap);
                reshapedFeatures = reshape(featureMap,H*W,C);
                gramMatrix = reshapedFeatures' * reshapedFeatures;
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            exportimg = app.transferImage;
            imwrite(exportimg,'styletransfer.jpg')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StyleTransferUIFigure = uifigure('Visible', 'off');
            app.StyleTransferUIFigure.Name = 'Style Transfer';
            app.UIAxes = uiaxes(app.StyleTransferUIFigure);
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.StyleTransferUIFigure);
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.StyleTransferUIFigure);
            title(app.UIAxes_3, 'Title')
            app.UIAxes_3.Visible = 'off';
            app.Input_1_Button = uibutton(app.StyleTransferUIFigure, 'push');
            app.Input_1_Button.ButtonPushedFcn = createCallbackFcn(app, @Input_1_ButtonPushed, true);
            app.Input_1_Button.Text = 'Content Image';
            app.Input_2_Button = uibutton(app.StyleTransferUIFigure, 'push');
            app.Input_2_Button.ButtonPushedFcn = createCallbackFcn(app, @Input_2_ButtonPushed, true);
            app.Input_2_Button.Text = 'Style Image';
            app.StartButton = uibutton(app.StyleTransferUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Label = uilabel(app.StyleTransferUIFigure);
            app.Label.Text = '';
            app.ExportButton = uibutton(app.StyleTransferUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.IterationsEditFieldLabel = uilabel(app.StyleTransferUIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.StyleTransferUIFigure, 'text');
            % Show the figure after all components are created
            app.StyleTransferUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StyleTransfer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StyleTransferUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StyleTransferUIFigure)
        end
    end
end